classdef pngdefringe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PNGDefringeUIFigure    matlab.ui.Figure
        ProcessFileButton      matlab.ui.control.Button
        ProcessFolderButton    matlab.ui.control.Button
        DefringeLevelLabel     matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        ReadyLabel             matlab.ui.control.Label
        TransparentColorLabel  matlab.ui.control.Label
        Button                 matlab.ui.control.Button
    end


    properties (Access = private)
        ImageLoadPath % Directory location
        ImageSavePath % Directory location
        FolderLoadPath % Directory location
        FolderSavePath % Directory location
    end


    methods (Access = private)

        % Code that executes after component creation
        function Starting(app)
            app.ImageLoadPath = pwd; % Initialize directory location
            app.ImageSavePath = pwd; % Initialize directory location
            app.FolderLoadPath = pwd; % Initialize directory location
            app.FolderSavePath = pwd; % Initialize directory location
        end

        % Button pushed function: ProcessFileButton
        function FileProcess(app, event)
            [ImageLoadName,ImageLoadLoc] = uigetfile('*.png','Load PNG',app.ImageLoadPath); % get file
            
            if ImageLoadLoc ~= 0 % If user hits cancel on load dont execute
                app.ImageLoadPath = ImageLoadLoc; % save valid location
                [ImageSaveName,ImageSaveLoc] = uiputfile('*.png','Save PNG',app.ImageSavePath); % get save file
                
                if ImageSaveLoc ~= 0 % If user hits cancel on save dont execute
                    app.ImageSavePath = ImageSaveLoc; % save valid location
                    app.ReadyLabel.Text = ['Processing ' ImageLoadName]; % update user
                    drawnow % draw text update
                    
                    % Convert level into numerical value
                    switch app.DropDown.Value
                        case '0'
                            Levels = 0;
                        case '1'
                            Levels = 1;
                        case '2'
                            Levels = 2;
                        case '3'
                            Levels = 3;
                        otherwise
                            Levels = 4;
                    end
                    
                    defringe(fullfile(ImageLoadLoc,ImageLoadName),fullfile(ImageSaveLoc,ImageSaveName),Levels,app.Button.BackgroundColor); % process image
                    
                    app.ReadyLabel.Text = 'Done'; % update user
                    drawnow % draw text update
                end
            end
        end

        % Button pushed function: ProcessFolderButton
        function FolderProcess(app, event)
            LoadPath = uigetdir(app.FolderLoadPath,'Load Directory'); % get folder
            
            if LoadPath ~= 0 % If user hits cancel on load
                app.FolderLoadPath = LoadPath; % Save valid folder
                SavePath = uigetdir(app.FolderSavePath,'Save Directory'); % get folder
                
                if SavePath ~= 0 % If user hits cancel on save
                    app.FolderSavePath = SavePath; % Save valid folder
                    
                    % Convert level into numerical value
                    switch app.DropDown.Value
                        case '0'
                            Levels = 0;
                        case '1'
                            Levels = 1;
                        case '2'
                            Levels = 2;
                        case '3'
                            Levels = 3;
                        otherwise
                            Levels = 4;
                    end
                    
                    FilePattern = fullfile(LoadPath, '*.png'); % Find pngs
                    Files = dir(FilePattern); % Find pngs in input folder
                    
                    for k = 1 : length(Files) % Go through each PNG
                        
                        BaseFileName = Files(k).name; % get file name
                        FullFileName = fullfile(Files(k).folder, BaseFileName); % input file location
                        OutputFileName = fullfile(SavePath, BaseFileName); % output file location
    
                        app.ReadyLabel.Text = ['Processing ' BaseFileName]; % update user
                        drawnow % draw text update
                        
                        defringe(FullFileName,OutputFileName,Levels,app.Button.BackgroundColor); % process image
                    end
                    
                    app.ReadyLabel.Text = 'Done'; % update user
                    drawnow % draw text update
                end
            end
        end

        % Button pushed function: Button
        function BaseColor(app, event)
            color = uisetcolor(app.Button.BackgroundColor); % get button color
            app.Button.BackgroundColor = color; % set background color
            drawnow % draw color update
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PNGDefringeUIFigure = uifigure;
            app.PNGDefringeUIFigure.Name = 'PNG Defringe';
            app.ProcessFileButton = uibutton(app.PNGDefringeUIFigure, 'push');
            app.ProcessFileButton.ButtonPushedFcn = createCallbackFcn(app, @FileProcess, true);
            app.ProcessFileButton.Text = 'Process File';
            app.ProcessFolderButton = uibutton(app.PNGDefringeUIFigure, 'push');
            app.ProcessFolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderProcess, true);
            app.ProcessFolderButton.Text = 'Process Folder';
            app.DefringeLevelLabel = uilabel(app.PNGDefringeUIFigure);
            app.DefringeLevelLabel.Text = 'Defringe Level:';
            app.DropDown = uidropdown(app.PNGDefringeUIFigure);
            app.DropDown.Items = {'0', '1', '2', '3', 'Full'};
            app.DropDown.Value = 'Full';
            app.ReadyLabel = uilabel(app.PNGDefringeUIFigure);
            app.ReadyLabel.Text = 'Ready';
            app.TransparentColorLabel = uilabel(app.PNGDefringeUIFigure);
            app.TransparentColorLabel.Text = 'Transparent Color:';
            app.Button = uibutton(app.PNGDefringeUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @BaseColor, true);
            app.Button.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = pngdefringe

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PNGDefringeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Starting)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PNGDefringeUIFigure)
        end
    end
end